classdef water_tank < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaterTankUIFigure        matlab.ui.Figure
        Tank1GaugeLabel          matlab.ui.control.Label
        Tank1Gauge               matlab.ui.control.LinearGauge
        SetValueGaugeLabel       matlab.ui.control.Label
        SetValueGauge            matlab.ui.control.LinearGauge
        ControlSignalGaugeLabel  matlab.ui.control.Label
        ControlSignalGauge       matlab.ui.control.LinearGauge
        SetValueKnobLabel        matlab.ui.control.Label
        SetValueKnob             matlab.ui.control.Knob
        MeasuredValueGaugeLabel  matlab.ui.control.Label
        MeasuredValueGauge       matlab.ui.control.LinearGauge
        Tank2GaugeLabel          matlab.ui.control.Label
        Tank2Gauge               matlab.ui.control.LinearGauge
        UIAxes                   matlab.ui.control.UIAxes
        ConnectButton            matlab.ui.control.Button
        PortDropDownLabel        matlab.ui.control.Label
        PortDropDown             matlab.ui.control.DropDown
        DisconnectButton         matlab.ui.control.Button
        ValvesPanel              matlab.ui.container.Panel
        Tank2SwitchLabel         matlab.ui.control.Label
        Tank2Switch              matlab.ui.control.RockerSwitch
        Tank12SwitchLabel        matlab.ui.control.Label
        Tank12Switch             matlab.ui.control.RockerSwitch
        Tank1SwitchLabel         matlab.ui.control.Label
        Tank1Switch              matlab.ui.control.RockerSwitch
        KpKnobLabel              matlab.ui.control.Label
        KpKnob                   matlab.ui.control.Knob
        KdKnobLabel              matlab.ui.control.Label
        KdKnob                   matlab.ui.control.Knob
        KiKnobLabel              matlab.ui.control.Label
        KiKnob                   matlab.ui.control.Knob
        KpGaugeLabel             matlab.ui.control.Label
        KpGauge                  matlab.ui.control.Gauge
        KiGaugeLabel             matlab.ui.control.Label
        KiGauge                  matlab.ui.control.Gauge
        KdGaugeLabel             matlab.ui.control.Label
        KdGauge                  matlab.ui.control.Gauge
        KpEditFieldLabel         matlab.ui.control.Label
        KpEditField              matlab.ui.control.NumericEditField
        KiEditFieldLabel         matlab.ui.control.Label
        KiEditField              matlab.ui.control.NumericEditField
        KdEditFieldLabel         matlab.ui.control.Label
        KdEditField              matlab.ui.control.NumericEditField
        RESETButton              matlab.ui.control.Button
        LampLabel                matlab.ui.control.Label
        Lamp                     matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        is_connected % If the water tank serial communication is running.
        
        array_set_values % Contains the history of set values
        array_measurements % The history of measurements
        array_control_signals % History of computed control signals
        old_error = 0 %Previous error
        e_integral = 0 %accumulated error
        
    end
    
    
    methods (Access = private)
        
        function [motor1 motor2] = controller(app, level1, level2)
            
            % Get the set value
            set_value = 0;
            motor1 = 0;
            motor2 = 0;
            try
                set_value = app.SetValueKnob.Value;
                
                kp = app.KpKnob.Value;
                ki = app.KiKnob.Value;
                kd = app.KdKnob.Value;
            catch
                return;
            end
            
            % Do the controll part
            error = set_value - level1;
            
            e_dot = error - app.old_error;
            app.e_integral = error + app.e_integral;
            motor1 = kp * error + ki * app.e_integral + kd * e_dot;
            app.old_error = error;
%             if (error > 0)
%                 %motor1 = 96;
%                 motor1=set_motor*set_value+(error*set_error)^2;
%             else
%                 motor1 = 0;
%             end
            
            % Store the data for plotting / do the plotting
            try
                app.array_measurements(1:end-1) = app.array_measurements(2:end);
                app.array_measurements(end) = level1;
               
                app.array_set_values(1:end-1) = app.array_set_values(2:end);
                app.array_set_values(end) = set_value;
               
                app.array_control_signals(1:end-1) = app.array_control_signals(2:end);
                app.array_control_signals(end) = motor1;
                
                hold(app.UIAxes,'off')
                plot(app.UIAxes, app.array_set_values);
                hold(app.UIAxes,'on')
                plot(app.UIAxes, app.array_measurements);
                plot(app.UIAxes, app.array_control_signals);
                app.UIAxes.YLim = [-10 110];
                
                
                % Update the GUI values
                app.MeasuredValueGauge.Value = level1;
                app.ControlSignalGauge.Value = motor1;
                
                app.KpGauge.Value = app.KpKnob.Value;
                app.KiGauge.Value = app.KiKnob.Value;
                app.KdGauge.Value = app.KdKnob.Value;
                
                app.KpEditField.Value = app.KpKnob.Value;
                app.KiEditField.Value = app.KiKnob.Value;
                app.KdEditField.Value = app.KdKnob.Value;
            
            catch
                return;
            end
                      
        end

        
    end


    methods (Access = private)
        function [level1 level2 valid] = parseWaterTankData(app, data)
            %        9 data bytes
            %        2 bytes yLevel 1                              (index 0,1)
            %        2 bytes yLevel 2                              (index 2,3)
            %        2 bytes                                       (index 4,5)
            %        2 bytes                                       (index 6,7)
            %        1 byte  Checksum = sum of the 8 bytes before. (index 8)
            
            [length tmp] = size(data);
            
            valid = false;
            level1 = -1;
            level2 = -1;
            
            if (length < 9)
                return;
            end
            
            % Use the checksum to find out where the start is located...
            offset = 0;
            
            while (9 + offset < length)
                % Checkshum
                checksum = uint8(mod(sum(data(1+offset:8+offset)),256));
                if (checksum == data(9+offset))
                    valid = true;
                    break;
                end
                offset = offset + 1;
            end
            
            if (valid ~= true)
                return
            end
            
            level1 = (data(2+offset)*256 + data(1+offset)) * 100.0 / 4095.0;
            level2 = (data(4+offset)*256 + data(3+offset)) * 100.0 / 4095.0;
        end
        
        function data = createWaterTankData(app, motor1, motor2, valve1, valve2, valve3, tiler1, filter2)
            %       2 bytes uMotor 1                              (index 0,1)
            %       2 bytes uMotor 2                              (index 2,3)
            %       2 bytes 0x0000                                (index 4,5)
            %       1 byte  D3=MV3, D2=MV2, D1=MV1                (index 6)
            %       1 byte  0x00                                  (index 7)
            %       2 bytes Filter time 1                         (index 8,9)
            %       2 bytes Filter time 2                         (index 10,11)
            %       1 byte  Checksum = sum of the 12 bytes before (index 12)
            data = zeros(1,13);
            
            u = ((4095.0 * motor1) / 100.0);
            data(1) = uint8(mod(u,256));
            data(2) = uint8(u / 256);
            
            u = ((4095.0 * motor2) / 100.0);
            data(3) = uint8(mod(u,256));
            data(4) = uint8(u / 256);
            
            if (valve1 == true)
                data(7) = data(7) + 1;
            end
            if (valve2 == true)
                data(7) = data(7) + 2;
            end
            if (valve3 == true)
                data(7) = data(7) + 4;
            end
            
            data(9) = hex2dec('FF');
            data(10) = hex2dec('0F');
            data(11) = hex2dec('FF');
            data(12) = hex2dec('0F');
            
            %sum_crc = sum(data(1:12))
            %final_crc = mod(sum_crc, 256)
            data(13) = uint8(mod(sum(data(1:12)),256));
            
        end
        
    end
    
    methods (Access = private)
        
        % Handles the communications, reads out the current measurement and set the motors and valve commands.
        function [level1 level2 valid] = waterTankCommunication(app, s, motor1, motor2, valve1, valve2, valve3)
            % Things for the protocolwaterTa
            SEND_BLOCK__      = 1;
            START_OF_BLOCK__  = 2;
            OK__              = 3;
            CHECKSUM_OK__     = 4;
            CHECKSUM_NOT_OK__ = 5;
            
            % Communication block goes here
            fwrite(s,SEND_BLOCK__, 'uint8'); % To trigger that measurment data should be sent.
            valid = false;
            level1 = -1;
            level2 = -1;
            
            to_read = s.BytesAvailable;
            
            if (s.BytesAvailable > 8)
                data_in = fread(s,s.BytesAvailable,'uint8');
                [level1 level2 valid] = parseWaterTankData(app, data_in);
                
                % Return that the checksum was ok, we don't want any
                % retransmissions anwyay...
                fwrite(s, CHECKSUM_OK__, 'uint8');
                
            end
            
            if (valid)
                
                % Call the control function
                [motor1 motor2] = controller(app, level1, level2);
                
                % Clear the buffer.
                while (s.BytesAvailable)
                    data_in = fread(s,s.BytesAvailable,'uint8');
                end
                
                fwrite(s,START_OF_BLOCK__, 'uint8');
                
                while (s.BytesAvailable < 1)
                    pause(0.1);
                end
                
                % Read a single char
                data_in = fread(s, 1, 'uint8');
                
                if (data_in(1) == OK__)
                    data_out = createWaterTankData(app, motor1, motor2, valve1, valve2, valve3, 1, 1);
                    fwrite(s,data_out,'uint8');
                    
                    while (s.BytesAvailable < 1)
                        pause(0.01);
                    end
                    % Read a single char
                    data_in = fread(s, 1, 'uint8');
                    if (data_in(1) == CHECKSUM_OK__)
                        %disp('CRC OK');
                    else
                        disp('CRC NOT OK');
                    end
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.is_connected = false;
            
            % Fill in the available serial ports
            available_ports = seriallist;
            app.PortDropDown.Items = available_ports;
            
            default_array_size = 100;
            app.array_set_values = zeros(1,default_array_size);
            app.array_measurements = zeros(1,default_array_size);
            app.array_control_signals = zeros(1,default_array_size);

        end

        % Value changing function: SetValueKnob
        function SetValueKnobValueChanging(app, event)
            changingValue = event.Value;
            app.SetValueGauge.Value = changingValue;
        end

        % Value changed function: SetValueKnob
        function SetValueKnobValueChanged(app, event)
            value = app.SetValueKnob.Value;
            app.SetValueGauge.Value = value;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            if (app.is_connected == true)
                % Should not happen
                return;
            end
            
            % Initialize the serial port, this callback will run the for-ever loop containing the communication.
            % Use the port from the drop down list
            port = app.PortDropDown.Value;
            s = serial(port, 'BaudRate',57600)
            
            fopen(s);
            app.is_connected = true;
            
            is_connected_ = app.is_connected;
            
            % Variables used to interface the tank
            motor1 = 0;
            motor2 = 0;
            valve1 = false;
            valve2 = false;
            valve3 = false;
            
            set_value = 0;
            control_value = 0;
            measured_value = 0;
            
            while (is_connected_ == true)
                
                try
                    is_connected_ = app.is_connected; % Handle if app is deleted (GUI was closed)
                    valve1 = strcmp(app.Tank1Switch.Value, 'Open');
                    valve2 = strcmp(app.Tank2Switch.Value, 'Open');
                    valve3 = strcmp(app.Tank12Switch.Value, 'Open');
                    
                    set_value = app.SetValueGauge.Value;
                    
                catch
                    is_connected_ = false;
                    fclose(s);
                    return;
                end
                [level1 level2 valid] = waterTankCommunication(app, s, motor1, motor2, valve1, valve2, valve3);
                
                if (valid)
                    
                    % Control function
                    
                    
                    try
                        app.Tank1Gauge.Value = level1;
                        app.Tank2Gauge.Value = level2;
                    catch
                        is_connected_ = false;
                        fclose(s);
                        return;
                    end
                end
                
                pause(0.01); % Sleep for a bit
                
            end
            
            
            % Close the port - very crucial to be able to open the same port again(!)
            fclose(s);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.is_connected = false;
        end

        % Close request function: WaterTankUIFigure
        function WaterTankUIFigureCloseRequest(app, event)
            
            
            app.is_connected = false;
            
            delete(app);
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.old_error = 0;
            app.e_integral = 0;
            app.Lamp.HandleVisibility = 'off';
            app.SetValueKnob.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaterTankUIFigure = uifigure;
            app.WaterTankUIFigure.Name = 'Water Tank';
            app.WaterTankUIFigure.CloseRequestFcn = createCallbackFcn(app, @WaterTankUIFigureCloseRequest, true);
            app.Tank1GaugeLabel = uilabel(app.WaterTankUIFigure);
            app.Tank1GaugeLabel.Text = 'Tank 1';
            app.Tank1Gauge = uigauge(app.WaterTankUIFigure, 'linear');
            app.SetValueGaugeLabel = uilabel(app.WaterTankUIFigure);
            app.SetValueGaugeLabel.Text = 'Set Value';
            app.SetValueGauge = uigauge(app.WaterTankUIFigure, 'linear');
            app.ControlSignalGaugeLabel = uilabel(app.WaterTankUIFigure);
            app.ControlSignalGaugeLabel.Text = 'Control Signal';
            app.ControlSignalGauge = uigauge(app.WaterTankUIFigure, 'linear');
            app.SetValueKnobLabel = uilabel(app.WaterTankUIFigure);
            app.SetValueKnobLabel.Text = 'Set Value Knob';
            app.SetValueKnob = uiknob(app.WaterTankUIFigure, 'continuous');
            app.SetValueKnob.ValueChangedFcn = createCallbackFcn(app, @SetValueKnobValueChanged, true);
            app.SetValueKnob.ValueChangingFcn = createCallbackFcn(app, @SetValueKnobValueChanging, true);
            app.MeasuredValueGaugeLabel = uilabel(app.WaterTankUIFigure);
            app.MeasuredValueGaugeLabel.Text = 'Measured Value';
            app.MeasuredValueGauge = uigauge(app.WaterTankUIFigure, 'linear');
            app.Tank2GaugeLabel = uilabel(app.WaterTankUIFigure);
            app.Tank2GaugeLabel.Text = 'Tank 2';
            app.Tank2Gauge = uigauge(app.WaterTankUIFigure, 'linear');
            app.UIAxes = uiaxes(app.WaterTankUIFigure);
            title(app.UIAxes, 'Plotter')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Set Value / Measured Value / Control Signal / Error')
            app.ConnectButton = uibutton(app.WaterTankUIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.PortDropDownLabel = uilabel(app.WaterTankUIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.WaterTankUIFigure);
            app.PortDropDown.Items = {};
            app.PortDropDown.Editable = 'on';
            app.PortDropDown.Value = {};
            app.DisconnectButton = uibutton(app.WaterTankUIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ValvesPanel = uipanel(app.WaterTankUIFigure);
            app.ValvesPanel.Title = 'Valves';
            app.Tank2SwitchLabel = uilabel(app.ValvesPanel);
            app.Tank2SwitchLabel.Text = 'Tank 2';
            app.Tank2Switch = uiswitch(app.ValvesPanel, 'rocker');
            app.Tank2Switch.Items = {'Close', 'Open'};
            app.Tank2Switch.Value = 'Close';
            app.Tank12SwitchLabel = uilabel(app.ValvesPanel);
            app.Tank12SwitchLabel.Text = 'Tank 1-2';
            app.Tank12Switch = uiswitch(app.ValvesPanel, 'rocker');
            app.Tank12Switch.Items = {'Close', 'Open'};
            app.Tank12Switch.Value = 'Close';
            app.Tank1SwitchLabel = uilabel(app.ValvesPanel);
            app.Tank1SwitchLabel.Text = 'Tank 1';
            app.Tank1Switch = uiswitch(app.ValvesPanel, 'rocker');
            app.Tank1Switch.Items = {'Close', 'Open'};
            app.Tank1Switch.Value = 'Close';
            app.KpKnobLabel = uilabel(app.WaterTankUIFigure);
            app.KpKnobLabel.Text = 'Kp';
            app.KpKnob = uiknob(app.WaterTankUIFigure, 'continuous');
            app.KpKnob.Limits = [0 10];
            app.KpKnob.Value = 5;
            app.KdKnobLabel = uilabel(app.WaterTankUIFigure);
            app.KdKnobLabel.Text = 'Kd';
            app.KdKnob = uiknob(app.WaterTankUIFigure, 'continuous');
            app.KdKnob.Limits = [0 10];
            app.KdKnob.Value = 3;
            app.KiKnobLabel = uilabel(app.WaterTankUIFigure);
            app.KiKnobLabel.Text = 'Ki';
            app.KiKnob = uiknob(app.WaterTankUIFigure, 'continuous');
            app.KiKnob.Limits = [0 3];
            app.KiKnob.Value = 0.6;
            app.KpGaugeLabel = uilabel(app.WaterTankUIFigure);
            app.KpGaugeLabel.Text = 'Kp';
            app.KpGauge = uigauge(app.WaterTankUIFigure, 'circular');
            app.KpGauge.Limits = [0 10];
            app.KiGaugeLabel = uilabel(app.WaterTankUIFigure);
            app.KiGaugeLabel.Text = 'Ki';
            app.KiGauge = uigauge(app.WaterTankUIFigure, 'circular');
            app.KiGauge.Limits = [0 3];
            app.KdGaugeLabel = uilabel(app.WaterTankUIFigure);
            app.KdGaugeLabel.Text = 'Kd';
            app.KdGauge = uigauge(app.WaterTankUIFigure, 'circular');
            app.KdGauge.Limits = [0 10];
            app.KpEditFieldLabel = uilabel(app.WaterTankUIFigure);
            app.KpEditFieldLabel.Visible = 'off';
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.WaterTankUIFigure, 'numeric');
            app.KpEditField.Editable = 'off';
            app.KiEditFieldLabel = uilabel(app.WaterTankUIFigure);
            app.KiEditFieldLabel.Visible = 'off';
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.WaterTankUIFigure, 'numeric');
            app.KiEditField.Editable = 'off';
            app.KdEditFieldLabel = uilabel(app.WaterTankUIFigure);
            app.KdEditFieldLabel.Visible = 'off';
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.WaterTankUIFigure, 'numeric');
            app.KdEditField.Editable = 'off';
            app.RESETButton = uibutton(app.WaterTankUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.LampLabel = uilabel(app.WaterTankUIFigure);
            app.LampLabel.Visible = 'off';
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.WaterTankUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = water_tank

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaterTankUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaterTankUIFigure)
        end
    end
end